classdef tempapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        SortbySwitch                  matlab.ui.control.Switch
        SortbySwitchLabel             matlab.ui.control.Label
        SubmitButton                  matlab.ui.control.Button
        SelectaspecificyearEditField  matlab.ui.control.EditField
        SelectaspecificyearEditFieldLabel  matlab.ui.control.Label
        ZoomSlider                    matlab.ui.control.Slider
        ZoomSliderLabel               matlab.ui.control.Label
        DisplayStdButton              matlab.ui.control.Button
        ChooseamonthSlider            matlab.ui.control.Slider
        ChooseamonthSliderLabel       matlab.ui.control.Label
        LoadCsvButton                 matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         time
         annual_avg
         annual_stds
    end
    
    methods (Access = private)
        
        %function to change view to each of the gui to plot based on years or months
        function change_view_to(app,time)
            if strcmpi(time, "years")
                % turn on the components that are meant to be used for the months view
                app.DisplayStdButton.Visible="on" ; 
                app.ChooseamonthSlider.Visible="on";
                app.ChooseamonthSliderLabel.Visible="on";
                % turn off the components that are non usable for the years view
                app.ZoomSlider.Visible="off" ;
                app.ZoomSliderLabel.Visible="off";
                app.SelectaspecificyearEditFieldLabel.Visible="off";
                app.SelectaspecificyearEditField.Visible="off";
                app.SubmitButton.Visible="off";
                % plot
                cla(app.UIAxes,"reset") % first reset axes
                plot(app.UIAxes, app.time, app.annual_avg);

                
            else 
                % turn off the components that are meant to be used for the months view
                app.DisplayStdButton.Visible="off" ; 
                app.ChooseamonthSlider.Visible="off";
                app.ChooseamonthSliderLabel.Visible="off";
                % turn on the components that are non usable for the years view
                app.ZoomSlider.Visible="on" ;
                app.ZoomSliderLabel.Visible="on";
                app.SelectaspecificyearEditField.Visible="on";
                app.SelectaspecificyearEditFieldLabel.Visible="on";
                app.SubmitButton.Visible="on";
                cla(app.UIAxes,"reset")
                %plot
                
            end
        end
        % function for reading data and applying cleansing
        function read_data_values(app, fullpath)
            data = readtable(fullpath);
            % Handle the nan values with filmissing by previous row in order to preserve continuity on the (monthly) plot
            data=fillmissing(data,"previous");
            % average=annual
            app.annual_avg = data.Annual ;
            app.time = data.Year;
            % Find the stds per year/ For the calculation we ignore the null values to avoid errors
            app.annual_stds = std(data{:,4:16}, [], 2) ; 
        end 

        function plot_bounded_std(app,time,avg,std)
            cla(app.UIAxes, "reset")
            x_plot = [time' fliplr(time')];                                            % Transposed To Row Vectors
            y_plot = [(avg-std)' fliplr((avg+std)')];                            % Transposed To Row Vectors
            hold(app.UIAxes,'on')                
            plot(app.UIAxes,time, avg);
            patch(app.UIAxes, x_plot, y_plot, 'g', 'FaceAlpha',0.3, 'EdgeColor','g', 'EdgeAlpha',0.8)
            hold(app.UIAxes,'off')            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % when starting the app, demosntrate the per month view
            change_view_to(app,"months")
        end

        % Button pushed function: LoadCsvButton
        function LoadCsvButtonPushed(app, event)
            
            % input file through ui
            [file, path] = uigetfile({'*.csv'},'File Selector');
            fullpath = strcat(path,file);
            
            % check if file is not in csv format and print error msg
            if isequal(file,0)
                msgbox('Please input a CSV file')
            
            % Here check if the csv is exactly in the format we want
            else 
            % read data  
            read_data_values(app, fullpath)
            %plot monthly plot

            end


        end

        % Value changing function: ChooseamonthSlider
        function ChooseamonthSliderValueChanging(app, event)
            %changingValue = event.Value;
            %plot(app.UIAxes, app.time, app.annual_avg)
               
        end

        % Value changed function: SortbySwitch
        function SortbySwitchValueChanged(app, event)
            value = app.SortbySwitch.Value;
            if strcmpi(value, "Months") 
                change_view_to(app,"months")
            else 
                change_view_to(app,"years")
            end

        end

        % Button pushed function: DisplayStdButton
        function DisplayStdButtonPushed(app, event)
            plot_bounded_std(app,app.time,app.annual_avg,app.annual_stds)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Temperature ℃')
            zlabel(app.UIAxes, 'Z')
            app.LoadCsvButton = uibutton(app.UIFigure, 'push');
            app.LoadCsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCsvButtonPushed, true);
            app.LoadCsvButton.Text = 'Load Csv';
            app.ChooseamonthSliderLabel = uilabel(app.UIFigure);
            app.ChooseamonthSliderLabel.Text = 'Choose a month ';
            app.ChooseamonthSlider = uislider(app.UIFigure);
            app.ChooseamonthSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ChooseamonthSlider.MajorTickLabels = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'June', 'July', 'Aug', 'Sept', 'Oct', 'Nov', 'Dec', ''};
            app.ChooseamonthSlider.ValueChangingFcn = createCallbackFcn(app, @ChooseamonthSliderValueChanging, true);
            app.ChooseamonthSlider.MinorTicks = [];
            app.DisplayStdButton = uibutton(app.UIFigure, 'push');
            app.DisplayStdButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayStdButtonPushed, true);
            app.DisplayStdButton.Text = 'Display Std';
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.SelectaspecificyearEditFieldLabel = uilabel(app.UIFigure);
            app.SelectaspecificyearEditFieldLabel.Text = 'Select a specific year';
            app.SelectaspecificyearEditField = uieditfield(app.UIFigure, 'text');
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.Text = 'Submit';
            app.SortbySwitchLabel = uilabel(app.UIFigure);
            app.SortbySwitchLabel.Text = 'Sort by';
            app.SortbySwitch = uiswitch(app.UIFigure, 'slider');
            app.SortbySwitch.Items = {'Months', 'Years'};
            app.SortbySwitch.ValueChangedFcn = createCallbackFcn(app, @SortbySwitchValueChanged, true);
            app.SortbySwitch.Value = 'Months';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tempapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end